classdef seegdb_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        SortbyDropDown         matlab.ui.control.DropDown
        SortbyDropDownLabel    matlab.ui.control.Label
        SortButton             matlab.ui.control.Button
        DistDropDown           matlab.ui.control.DropDown
        DistDropDownLabel      matlab.ui.control.Label
        RenderButton           matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        subjectsdirLabel       matlab.ui.control.Label
        SetsubjectsdirButton   matlab.ui.control.Button
        StartsearchButton      matlab.ui.control.Button
        CoordDropDown          matlab.ui.control.DropDown
        CoordDropDownLabel     matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        XEditField             matlab.ui.control.NumericEditField
        ROILabelDropDown       matlab.ui.control.DropDown
        ROILabelDropDownLabel  matlab.ui.control.Label
        SettargetDropDown      matlab.ui.control.DropDown
        subjectsDropDown       matlab.ui.control.DropDown
        subjectsDropDownLabel  matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        SetelectrodedirButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        seegdb_obj; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.seegdb_obj.subjects_dir =getenv('SUBJECTS_DIR');
            app.subjectsdirLabel.Text=app.seegdb_obj.subjects_dir;

            app.seegdb_obj.electrode_file='';
            app.seegdb_obj.path='';
            app.seegdb_obj.roi_xyz=[];
            app.seegdb_obj.roi_idx=[];
            app.seegdb_obj.roi_file=[];

            app.seegdb_obj.sortby_idx=1;

            app.StartsearchButton.Enable='off';
            app.RenderButton.Enable='off';
        end

        % Button pushed function: SetelectrodedirButton
        function SetelectrodedirButtonPushed(app, event)
            %scan dir

            app.seegdb_obj.path = uigetdir;

            try
                rootdir = app.seegdb_obj.path;
                if(ismac)
                    filelist = dir(fullfile(rootdir, '**/*.*'));  %get list of files and folders in any subfolder
                elseif(IsWindows)
                    filelist = dir(fullfile(rootdir, '**\*.*'));  %get list of files and folders in any subfolder                    
                end;
                filelist = filelist(~[filelist.isdir]);
                
                app.seegdb_obj.subject={};
                app.seegdb_obj.electrode_file={};
                for idx=1:length(filelist)
                    if(isempty(extract(filelist(idx).folder,app.seegdb_obj.subjects_dir)))
                        if(isempty(extract(filelist(idx).folder,'subjects')))

                            if(regexp(filelist(idx).name,regexptranslate('wildcard','elec*.mat'))) %find all 'elec*.mat' files

                                newStr = split(filelist(idx).folder,{app.seegdb_obj.path,'/'});
                                newStr_idx = find(~cellfun(@isempty,newStr));

                                app.seegdb_obj.subject{end+1}=newStr{newStr_idx(1)};
                                app.seegdb_obj.electrode_file{end+1}=sprintf('%s/%s',filelist(idx).folder,filelist(idx).name);
                            end;
                        end;
                    end;
                end;
                app.subjectsDropDown.Items=app.seegdb_obj.subject;

                if(length(app.subjectsDropDown.Items)>0)
                    app.SettargetDropDown.Enable=1;
                end;

                if(~isempty(app.seegdb_obj.electrode_file)&~isempty(app.seegdb_obj.path))
                    if(~isempty(app.seegdb_obj.roi_file))
                        app.StartsearchButton.Enable='on';
                    elseif(~isempty(app.seegdb_obj.roi_xyz))
                        app.StartsearchButton.Enable='on';
                    end;
                end;
            catch 
            end;
        end

        % Drop down opening function: SettargetDropDown
        function SettargetDropDownCallback(app, event)
           
            value = app.SettargetDropDown.Value;
            
            switch(value)
                case 'ROI/Label'
                    app.ROILabelDropDown.Enable=1;
                    app.XEditField.Enable=0;
                    app.YEditField.Enable=0;
                    app.ZEditField.Enable=0;

                    [filename, pathname, filterindex] = uigetfile(fullfile(pwd,'*.annot;*.label'),'select an annotation/label file');

                    try
                        app.seegdb_obj.roi_file={};
                        [dummy,fstem,ext]=fileparts(filename);
                        switch lower(ext)
                            case '.label'
                                file_label=sprintf('%s/%s',pathname,filename);
                                [app.seegdb_obj.roi_idx,d0,d1,d2, vv] = inverse_read_label(file_label);
                                app.seegdb_obj.roi_file{end+1}=file_label;
                                app.seegdb_obj.roi_xyz=[];

                                app.ROILabelDropDown.Items={filename};
                        end;
                    catch 
                    end;

                case 'Coordinates'
                    app.ROILabelDropDown.Items={};
                    app.ROILabelDropDown.Enable=0;
                    app.XEditField.Enable=1;
                    app.YEditField.Enable=1;
                    app.ZEditField.Enable=1;

                    app.seegdb_obj.roi_xyz=[app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value];
                    app.seegdb_obj.roi_idx=[];
                    app.seegdb_obj.roi_file={};
            end;

            if(~isempty(app.seegdb_obj.electrode_file)&~isempty(app.seegdb_obj.path))
                if(~isempty(app.seegdb_obj.roi_file))
                    app.StartsearchButton.Enable='on';
                elseif(~isempty(app.seegdb_obj.roi_xyz))
                    app.StartsearchButton.Enable='on';
                end;
            end;
        end

        % Button pushed function: StartsearchButton
        function startsearch(app, event)
            for f_idx=1:length(app.seegdb_obj.electrode_file)
                try
                    %make sure electrodes in MNI305 space
                    [output]=seegdb_electrode_file_parse(app.seegdb_obj.electrode_file{f_idx});
                    if(output==0)
                        fprintf('convert electrode file [%s] to MNI305 ...\n',app.seegdb_obj.electrode_file{f_idx})
                        [electrode_tmp]=seegdb_native2mni305(app.seegdb_obj.subject{f_idx}, app.seegdb_obj.electrode_file{f_idx}, app.seegdb_obj);
                    else
                        fprintf('load electrode file [%s] in MNI305 ...\n',app.seegdb_obj.electrode_file{f_idx})
                        tmp=load(app.seegdb_obj.electrode_file{f_idx});
                        electrode_tmp=tmp.electrode;
                    end
                catch
                    fprintf('[%s::%s] error in judging MNI305 vs. native spaace.\n',app.seegdb_obj.subject{f_idx}, app.seegdb_obj.electrode_file{f_idx});
                end;

                try
                    %calculate the distance
                    [app.seegdb_obj.distance]=seegdb_calc_distance(app.seegdb_obj.subjects_dir,'fsaverage', app.seegdb_obj.roi_file, electrode_tmp);
                    switch(app.DistDropDown.Value)
                        case 'COM' % d_COM
                            app.seegdb_obj.distance.metric='COM';
                        case 'MIN' % d_MIN
                            app.seegdb_obj.distance.metric='MIN';
                    end;
                    app.seegdb_obj.distance.subject=app.seegdb_obj.subject{f_idx};

                    [seegdb_table, app.seegdb_obj]=seegdb_updatetable(app.UITable.Data, app.seegdb_obj);
                    app.UITable.Data=seegdb_table;
                    
                    app.seegdb_obj.table=seegdb_table;

                    app.RenderButton.Enable='on';
                catch
                    fprintf('[%s::%s] error in calculating the distance between electrodes and ROI\n',app.seegdb_obj.subject{f_idx}, app.seegdb_obj.electrode_file{f_idx});
                    keyboard;
                end;
            end;
        end

        % Button pushed function: SetsubjectsdirButton
        function subjectsdir(app, event)
            try
                app.seegdb_obj.subjects_dir = uigetdir;
                app.subjectsdirLabel.Text=app.seegdb_obj.subjects_dir;


                if(~isempty(app.seegdb_obj.electrode_file)&~isempty(app.seegdb_obj.path))
                    if(~isempty(app.seegdb_obj.roi_file))
                        app.StartsearchButton.Enable='on';
                    elseif(~isempty(app.seegdb_obj.roi_xyz))
                        app.StartsearchButton.Enable='on';
                    end;
                end;
            catch
            end;

        end

        % Button pushed function: ResetButton
        function TabelReset(app, event)
            app.UITable.Data=[];

            app.RenderButton.Enable='off';

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.seegdb_obj.row_select=unique(indices(:,1));
            %fprintf('[%d]\n',indices);
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            output=seegdb_render(app.seegdb_obj);
        end

        % Value changed function: DistDropDown
        function DistDropDownValueChanged(app, event)
            switch(app.DistDropDown.Value)
                case 'COM' % d_COM
                    app.seegdb_obj.distance.metric='COM';
                case 'MIN' % d_MIN
                    app.seegdb_obj.distance.metric='MIN';
            end;

            try
            app.UITable.Data=[];
            [seegdb_table, app.seegdb_obj]=seegdb_updatetable(app.UITable.Data, app.seegdb_obj);
            app.UITable.Data=seegdb_table;

            app.seegdb_obj.table=seegdb_table;
            catch 
                fprintf('update distance error!\n');
                return;
            end;
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            %app.seegdb_obj.table=seegdb_table;  
            if(isfield(app.seegdb_obj,'table'))
                switch(app.seegdb_obj.sortby_idx)
                    case 'Distance'
                        sortby_idx=4;
                    case 'Subject'
                        sortby_idx=1;
                    case 'Electrode'
                        sortby_idx=2;
                    case 'X'
                        sortby_idx=5;
                    case 'Y'
                        sortby_idx=6;
                    case 'Z'
                        sortby_idx=7;
                end;
                seegdb_table = sortrows(app.seegdb_obj.table,sortby_idx);
            
                app.seegdb_obj.table=seegdb_table;  
                app.UITable.Data=seegdb_table;
            end;
        end

        % Value changed function: SortbyDropDown
        function SortbyDropDownValueChanged(app, event)
            value = app.SortbyDropDown.Value;
            app.seegdb_obj.sortby_idx=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SetelectrodedirButton = uibutton(app.GridLayout, 'push');
            app.SetelectrodedirButton.ButtonPushedFcn = createCallbackFcn(app, @SetelectrodedirButtonPushed, true);
            app.SetelectrodedirButton.Text = 'Set electrode dir.';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Subject'; 'Electrode'; 'Contact'; 'Distance'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.subjectsDropDownLabel = uilabel(app.GridLayout);
            app.subjectsDropDownLabel.Text = 'subjects';
            app.subjectsDropDown = uidropdown(app.GridLayout);
            app.subjectsDropDown.Items = {'[]'};
            app.subjectsDropDown.Value = '[]';
            app.SettargetDropDown = uidropdown(app.GridLayout);
            app.SettargetDropDown.Items = {'ROI/Label', 'Coordinates'};
            app.SettargetDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SettargetDropDownCallback, true);
            app.SettargetDropDown.Enable = 'off';
            app.SettargetDropDown.Value = 'ROI/Label';
            app.ROILabelDropDownLabel = uilabel(app.GridLayout);
            app.ROILabelDropDownLabel.Enable = 'off';
            app.ROILabelDropDownLabel.Text = 'ROI/Label';
            app.ROILabelDropDown = uidropdown(app.GridLayout);
            app.ROILabelDropDown.Items = {'[]'};
            app.ROILabelDropDown.Enable = 'off';
            app.ROILabelDropDown.Value = '[]';
            app.XEditField = uieditfield(app.GridLayout, 'numeric');
            app.XEditField.Enable = 'off';
            app.YEditField = uieditfield(app.GridLayout, 'numeric');
            app.YEditField.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.GridLayout);
            app.ZEditFieldLabel.Enable = 'off';
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout, 'numeric');
            app.ZEditField.Enable = 'off';
            app.CoordDropDownLabel = uilabel(app.GridLayout);
            app.CoordDropDownLabel.Enable = 'off';
            app.CoordDropDownLabel.Text = 'Coord';
            app.CoordDropDown = uidropdown(app.GridLayout);
            app.CoordDropDown.Items = {'MNI305', 'Native'};
            app.CoordDropDown.Enable = 'off';
            app.CoordDropDown.Value = 'MNI305';
            app.StartsearchButton = uibutton(app.GridLayout, 'push');
            app.StartsearchButton.ButtonPushedFcn = createCallbackFcn(app, @startsearch, true);
            app.StartsearchButton.Text = 'Start search';
            app.SetsubjectsdirButton = uibutton(app.GridLayout, 'push');
            app.SetsubjectsdirButton.ButtonPushedFcn = createCallbackFcn(app, @subjectsdir, true);
            app.SetsubjectsdirButton.Text = 'Set subjects dir.';
            app.subjectsdirLabel = uilabel(app.GridLayout);
            app.subjectsdirLabel.Text = 'subjectsdir';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @TabelReset, true);
            app.ResetButton.Text = 'Reset';
            app.RenderButton = uibutton(app.GridLayout, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.DistDropDownLabel = uilabel(app.GridLayout);
            app.DistDropDownLabel.Text = 'Dist.';
            app.DistDropDown = uidropdown(app.GridLayout);
            app.DistDropDown.Items = {'COM', 'MIN'};
            app.DistDropDown.ValueChangedFcn = createCallbackFcn(app, @DistDropDownValueChanged, true);
            app.DistDropDown.Value = 'COM';
            app.SortButton = uibutton(app.GridLayout, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.SortbyDropDownLabel = uilabel(app.GridLayout);
            app.SortbyDropDownLabel.Text = 'Sort by';
            app.SortbyDropDown = uidropdown(app.GridLayout);
            app.SortbyDropDown.Items = {'Distance', 'Subject', 'Electrode', 'X', 'Y', 'Z'};
            app.SortbyDropDown.ValueChangedFcn = createCallbackFcn(app, @SortbyDropDownValueChanged, true);
            app.SortbyDropDown.Value = 'Distance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seegdb_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end